classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        ImageDisp              matlab.ui.control.Image
        Credit                 matlab.ui.control.Hyperlink
        ExportData_Button      matlab.ui.control.Button
        FeatureDropDown        matlab.ui.control.DropDown
        FeatureLabel           matlab.ui.control.Label
        Area_Field             matlab.ui.control.NumericEditField
        Area_Text              matlab.ui.control.Label
        Number_of_Cells_Field  matlab.ui.control.NumericEditField
        Number_of_Cells_Text   matlab.ui.control.Label
        Segment_Button         matlab.ui.control.Button
        Help_Button            matlab.ui.control.Button
        Settings_Button        matlab.ui.control.Button
        SelectImageButton      matlab.ui.control.Button
        HistogramAxes          matlab.ui.control.UIAxes
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    properties (Access = private)
    Image; % Stores the uploaded image
    SegmentedImage; % Stores the segmented image
    net; % Stores the pre-trained CNN network
    Masks;
    Boxes;
    Labels;
    Scores;
    AnalysisData;
    end
    
    methods (Access = private)
        
        function [] = fixIMGDisplay(app, Image, ImageDisp) %function to display image file without labels properly
            %title(ImageAxes, []);
            %xlabel(ImageAxes, []);
            %ylabel(ImageAxes, []);
            %ImageAxes.XAxis.TickLabels = {};
            %ImageAxes.YAxis.TickLabels = {};
            %ImageAxes.PositionConstraint="outerposition";
            %ImageAxes.InnerPosition=ImageAxes.OuterPosition
    %         I = imshow(Image, 'Parent', app.ImageAxes, ...
    % 'XData', [1 app.ImageAxes.Position(3)], ...
    % 'YData', [1 app.ImageAxes.Position(4)]);
    %         box(ImageAxes,"on")
        if size(Image, 3)==1
            ImageDisp.ImageSource = repmat(Image, [1 1 3]);
        elseif size(Image, 3)==3
            ImageDisp.ImageSource = Image;
        end


        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            fixIMGDisplay(app, ones(520, 740)*0.9, app.ImageDisp);
        end

        % Callback function: ImageDisp, SelectImageButton
        function UploadImage(app, event)
            [file, path] = uigetfile({'*.png;*.jpg;*.tif'}, 'Select an Image');
            if isequal(file, 0)
                errordlg('No image selected!', 'Error');
                return;
            end
            app.Image = rescale(imread(fullfile(path, file)));
            
            % Convert to grayscale if necessary
            if size(app.Image, 3) == 3
                app.Image = mean(app.Image, 3);
            end
        

            % Display the uploaded image
            fixIMGDisplay(app, app.Image, app.ImageDisp);


        end

        % Button pushed function: Segment_Button
        function Segment(app, event)
              if isempty(app.Image)
        errordlg('Please upload an image first!', 'Error');
        return;
    end

    % Add MRCNNsrc path (Make sure this path is correct)
    addpath(genpath('MRCNNsrc'));

    % Load pre-trained CNN model if not already loaded
    if isempty(app.net)
        try
            app.net = load('ResN101-FINAL.mat', 'net'); % Load the network file
            app.net = app.net.net; % Extract the network object
            app.net.OverlapThreshold = 0.1;
        catch
            errordlg('Error loading CNN model. Check file path!', 'Error');
            return;
        end
    end

    % Perform cell segmentation
    try
        [app.Masks app.Labels app.Scores app.Boxes] = segmentCells(app.net, app.Image, "NumstrongestRegions",Inf, MinSize=[4 4], MaxSize=[80 80],SelectStrongest=true, SegmentThreshold=0.1);
    

        if (isempty(app.Masks))
            app.SegmentedImage = app.Image;
        else
            app.SegmentedImage = insertObjectMask(app.Image, app.Masks,Color=lines(size(app.Masks, 3)) );
        end
        % Display the segmented image with masks
        fixIMGDisplay(app, app.SegmentedImage, app.ImageDisp);
        
        app.AnalysisData = ExtractFeatures(app.Image, app.Masks, app.Boxes, 1);

        % Update the cell count field
        app.Number_of_Cells_Field.Value = size(app.AnalysisData, 1);
        app.Area_Field.Value = mean(app.AnalysisData.Area);

    catch ME
        errordlg(['Segmentation error: ', ME.message], 'Error');
    end
        PlotHistogram(app,1);


        end

        % Value changed function: FeatureDropDown
        function PlotHistogram(app, event)
            value = app.FeatureDropDown.Value;

            % Extract the selected feature's values
            featureData = app.AnalysisData.(value);
            
            % Plot histogram
            histogram(featureData, 'Parent',app.HistogramAxes);
            xlabel(app.HistogramAxes, value);
            ylabel(app.HistogramAxes, 'Frequency');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'turbo');
            app.UIFigure.Name = 'MATLAB App';
            app.HistogramAxes = uiaxes(app.UIFigure);
            ylabel(app.HistogramAxes, 'Frequency')
            colormap(app.HistogramAxes, 'turbo')
            app.SelectImageButton = uibutton(app.UIFigure, 'push');
            app.SelectImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImage, true);
            app.SelectImageButton.Text = 'Select Image';
            app.Settings_Button = uibutton(app.UIFigure, 'push');
            app.Settings_Button.Icon = fullfile(pathToMLAPP, '3524659.png');
            app.Settings_Button.Text = '';
            app.Help_Button = uibutton(app.UIFigure, 'push');
            app.Help_Button.Text = '?';
            app.Segment_Button = uibutton(app.UIFigure, 'push');
            app.Segment_Button.ButtonPushedFcn = createCallbackFcn(app, @Segment, true);
            app.Segment_Button.Text = 'Segment';
            app.Number_of_Cells_Text = uilabel(app.UIFigure);
            app.Number_of_Cells_Text.Text = 'Number of Cells in Image:';
            app.Number_of_Cells_Field = uieditfield(app.UIFigure, 'numeric');
            app.Area_Text = uilabel(app.UIFigure);
            app.Area_Text.Text = 'Average Area (px^2):';
            app.Area_Field = uieditfield(app.UIFigure, 'numeric');
            app.FeatureLabel = uilabel(app.UIFigure);
            app.FeatureLabel.Text = 'Feature:';
            app.FeatureDropDown = uidropdown(app.UIFigure);
            app.FeatureDropDown.Items = {'Area', 'Perimeter', 'STDeviation', 'AspectRatio', 'Eccentricity', 'Circularity', 'Solidity', 'MaxDiameter', 'Kurtosis'};
            app.FeatureDropDown.ValueChangedFcn = createCallbackFcn(app, @PlotHistogram, true);
            app.FeatureDropDown.Value = 'Area';
            app.ExportData_Button = uibutton(app.UIFigure, 'push');
            app.ExportData_Button.Text = 'Export Data';
            app.Credit = uihyperlink(app.UIFigure);
            app.Credit.URL = 'https://github.com/TechAvi-eng/4th-Year-Image-Processing-Project';
            app.Credit.Text = 'As Yet Unnamed v0.2';
            app.ImageDisp = uiimage(app.UIFigure);
            app.ImageDisp.ImageClickedFcn = createCallbackFcn(app, @UploadImage, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end